classdef circular_fft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        RmSliderLabel  matlab.ui.control.Label
        RmSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Property % Description
        x=(-2:0.05:2)
        y=(-2:0.05:2)
        A
        R=0.2
        r
        i_index
        j_index
        fft_v
        fft_val
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.A=app.y.'*app.x
            app.i_index=0
            for i=-2:0.05:2
                app.j_index=0
                app.i_index=app.i_index+1
                for j=-2:0.05:2
                    app.j_index=app.j_index+1
                    app.r=sqrt(i^2+j^2)
                    if app.r <=app.R
                        app.A(app.i_index,app.j_index)=1
                    else
                        app.A(app.i_index,app.j_index)=0
                    end
                end
            end
            mesh(app.UIAxes,app.x,app.y,app.A)
            app.fft_v=abs(fft2(app.A))
            app.fft_val=fftshift(app.fft_v)
            mesh(app.UIAxes2,app.x,app.y,app.fft_val)
        end

        % Value changed function: RmSlider
        function RmSliderValueChanged(app, event)
         app.A=app.y.'*app.x
         app.i_index=0   
         app.R = app.RmSlider.Value;
            for i=-2:0.05:2
                app.j_index=0
                app.i_index=app.i_index+1
                for j=-2:0.05:2
                    app.j_index=app.j_index+1
                    app.r=sqrt(i^2+j^2)
                    if app.r <=app.R
                        app.A(app.i_index,app.j_index)=1
                    else
                        app.A(app.i_index,app.j_index)=0
                    end
                end
            end
            mesh(app.UIAxes,app.x,app.y,app.A)
            app.fft_v=abs(fft2(app.A))
            app.fft_val=fftshift(app.fft_v)
            mesh(app.UIAxes2,app.x,app.y,app.fft_val)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'circular aperture')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'fft of circular aperture')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.RmSliderLabel = uilabel(app.UIFigure);
            app.RmSliderLabel.Text = 'R/m';
            app.RmSlider = uislider(app.UIFigure);
            app.RmSlider.Limits = [0 2];
            app.RmSlider.ValueChangedFcn = createCallbackFcn(app, @RmSliderValueChanged, true);
            app.RmSlider.Value = 0.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = circular_fft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end