classdef diffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        lambdaEditFieldLabel   matlab.ui.control.Label
        lambdaEditField        matlab.ui.control.NumericEditField
        dEditFieldLabel        matlab.ui.control.Label
        dEditField             matlab.ui.control.NumericEditField
        LEditFieldLabel        matlab.ui.control.Label
        LEditField             matlab.ui.control.NumericEditField
        HEditFieldLabel        matlab.ui.control.Label
        HEditField             matlab.ui.control.NumericEditField
        ScreenXEditFieldLabel  matlab.ui.control.Label
        ScreenXEditField       matlab.ui.control.NumericEditField
        ScreenYEditFieldLabel  matlab.ui.control.Label
        ScreenYEditField       matlab.ui.control.NumericEditField
        Button                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            lambda=app.lambdaEditField.Value
            d=app.dEditField.Value
            L=app.LEditField.Value
            H=app.HEditField.Value
            ScreenX=app.ScreenXEditField.Value
            ScreenY=app.ScreenYEditField.Value
            
            x=linspace(-H/2,H/2,1048)
            theta=atan(x/L)
            I=(sin(d*pi*sin(theta)/lambda)./(d*pi*sin(theta)/lambda)).^2
            II=repmat(I,[ScreenY 1])
            
            imshow(nthroot(II,5),'parent',app.UIAxes) %增强对比度
            colormap(app.UIAxes,'gray')
            plot(app.UIAxes2,x,I)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.lambdaEditFieldLabel = uilabel(app.UIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScreenXEditFieldLabel = uilabel(app.UIFigure);
            app.ScreenXEditFieldLabel.Text = 'ScreenX';
            app.ScreenXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScreenYEditFieldLabel = uilabel(app.UIFigure);
            app.ScreenYEditFieldLabel.Text = 'ScreenY';
            app.ScreenYEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end