classdef fft_2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes2        matlab.ui.control.UIAxes
        XmSliderLabel  matlab.ui.control.Label
        XmSlider       matlab.ui.control.Slider
        YmSliderLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
        YmSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Property % Description
        X=0.2
        Y=0.1
        x=(-2:0.05:2)
        y=(-2:0.05:2)
        A
        i_index
        J_index
        fft_v
        fft_val
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.A=app.y.'*app.x
            app.i_index=0
            for i=-2:0.05:2
                app.J_index=0
                app.i_index=app.i_index+1
                for j=-2:0.05:2
                    app.J_index=app.J_index+1
                    if abs(i) <=app.Y & abs(j)<=app.X
                        app.A(app.i_index,app.J_index)=1
                    else
                        app.A(app.i_index,app.J_index)=0
                    end
                end
            end
           imshow(app.A,'parent',app.UIAxes)
           app.fft_v=abs(fft2(app.A))
           app.fft_val=fftshift(app.fft_v)
           imshow(app.fft_val/max(max(app.fft_val)),'parent',app.UIAxes2)
        end

        % Value changed function: XmSlider
        function XmSliderValueChanged(app, event)
            app.X = app.XmSlider.Value;
            app.A=app.y.'*app.x
            app.i_index=0
            for i=-2:0.05:2
                app.J_index=0
                app.i_index=app.i_index+1
                for j=-2:0.05:2
                    app.J_index=app.J_index+1
                    if abs(i) <=app.Y & abs(j)<=app.Y
                        app.A(app.i_index,app.J_index)=1
                    else
                        app.A(app.i_index,app.J_index)=0
                    end
                end
            end
           imshow(app.A,'parent',app.UIAxes)
           app.fft_v=abs(fft2(app.A))
           app.fft_val=fftshift(app.fft_v)
           imshow(app.fft_val/max(max(app.fft_val)),'parent',app.UIAxes2)
        end

        % Value changed function: YmSlider
        function YmSliderValueChanged(app, event)
            app.Y = app.YmSlider.Value;
            app.A=app.y.'*app.x
            app.i_index=0
            for i=-2:0.05:2
                app.J_index=0
                app.i_index=app.i_index+1
                for j=-2:0.05:2
                    app.J_index=app.J_index+1
                    if abs(i) <=app.Y & abs(j)<=app.X
                        app.A(app.i_index,app.J_index)=1
                    else
                        app.A(app.i_index,app.J_index)=0
                    end
                end
            end
           imshow(app.A,'parent',app.UIAxes)
           app.fft_v=abs(fft2(app.A))
           app.fft_val=fftshift(app.fft_v)
           imshow(app.fft_val/max(max(app.fft_val)),'parent',app.UIAxes2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.XmSliderLabel = uilabel(app.UIFigure);
            app.XmSliderLabel.Text = 'X/m';
            app.XmSlider = uislider(app.UIFigure);
            app.XmSlider.Limits = [0 0.5];
            app.XmSlider.ValueChangedFcn = createCallbackFcn(app, @XmSliderValueChanged, true);
            app.XmSlider.Value = 0.2;
            app.YmSliderLabel = uilabel(app.UIFigure);
            app.YmSliderLabel.Text = 'Y/m';
            app.YmSlider = uislider(app.UIFigure);
            app.YmSlider.Limits = [0 0.5];
            app.YmSlider.ValueChangedFcn = createCallbackFcn(app, @YmSliderValueChanged, true);
            app.YmSlider.Value = 0.1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fft_2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end