classdef fresneldiffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        nmSliderLabel  matlab.ui.control.Label
        nmSlider       matlab.ui.control.Slider
        dmSliderLabel  matlab.ui.control.Label
        dmSlider       matlab.ui.control.Slider
        Image          matlab.ui.control.Image
    end

    
    properties (Access = public)
        %直边菲涅尔衍射
        Property % Description
        ScreenX=1048%观察屏横向像素
        ScreenY=350%观察屏纵向像素
        lambda=632.8e-9%单色光波长
        d=10%观察屏到直边的距离
        x=0.02%观察屏的长度
        upsilon0
        I,II
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.upsilon0 = linspace(-sqrt(2/(app.lambda*app.d))*app.x,sqrt(2/(app.lambda*app.d))*app.x,app.ScreenX)
            app.I=1/2*((1/2-fresnelc(-app.upsilon0)).^2+(1/2-fresnels(-app.upsilon0)).^2)
            app.II=repmat(app.I,[app.ScreenY 1])
            
            imshow(app.II,'Parent',app.UIAxes)
            plot(app.UIAxes2,linspace(-app.x/2,app.x/2,app.ScreenX),app.I)
        end

        % Value changing function: nmSlider
        function nmSliderValueChanging(app, event)
            app.lambda = event.Value*1e-9;
            app.upsilon0 = linspace(-sqrt(2/(app.lambda*app.d))*app.x,sqrt(2/(app.lambda*app.d))*app.x,app.ScreenX)
            app.I=1/2*((1/2-fresnelc(-app.upsilon0)).^2+(1/2-fresnels(-app.upsilon0)).^2)
            app.II=repmat(app.I,[app.ScreenY 1])
            
            imshow(app.II,'Parent',app.UIAxes)
            plot(app.UIAxes2,linspace(-app.x/2,app.x/2,app.ScreenX),app.I)
        end

        % Value changing function: dmSlider
        function dmSliderValueChanging(app, event)
            app.d = event.Value;
            app.upsilon0 = linspace(-sqrt(2/(app.lambda*app.d))*app.x,sqrt(2/(app.lambda*app.d))*app.x,app.ScreenX)
            app.I=1/2*((1/2-fresnelc(-app.upsilon0)).^2+(1/2-fresnels(-app.upsilon0)).^2)
            app.II=repmat(app.I,[app.ScreenY 1])
            
            imshow(app.II,'Parent',app.UIAxes)
            plot(app.UIAxes2,linspace(-app.x/2,app.x/2,app.ScreenX),app.I)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            app.nmSliderLabel = uilabel(app.UIFigure);
            app.nmSliderLabel.Text = '波长λ（nm）';
            app.nmSlider = uislider(app.UIFigure);
            app.nmSlider.Limits = [400 760];
            app.nmSlider.ValueChangingFcn = createCallbackFcn(app, @nmSliderValueChanging, true);
            app.nmSlider.Value = 632;
            app.dmSliderLabel = uilabel(app.UIFigure);
            app.dmSliderLabel.Text = '观察屏到直边的距离d（m）';
            app.dmSlider = uislider(app.UIFigure);
            app.dmSlider.Limits = [5 15];
            app.dmSlider.ValueChangingFcn = createCallbackFcn(app, @dmSliderValueChanging, true);
            app.dmSlider.Value = 10;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'fresnel.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fresneldiffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end