classdef interference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        Image              matlab.ui.control.Image
        mEditField_4Label  matlab.ui.control.Label
        mEditField_4       matlab.ui.control.NumericEditField
        mEditFieldLabel    matlab.ui.control.Label
        mEditField         matlab.ui.control.NumericEditField
        mEditField_2Label  matlab.ui.control.Label
        mEditField_2       matlab.ui.control.NumericEditField
        mEditField_3Label  matlab.ui.control.Label
        mEditField_3       matlab.ui.control.NumericEditField
        Button             matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
          
            lambda=app.mEditField_4.Value
            d=app.mEditField.Value
            L=app.mEditField_2.Value
            H=app.mEditField_3.Value
            x01=d/2
            x02=-d/2

            y=linspace(-H/2,H/2,501)
            x=linspace(-H/2,H/2,501)
            [X Y]=meshgrid(x,y)
            I2=zeros(size(X))

            R1=sqrt((y-x01).^2+L^2)
            R2=sqrt((y-x02).^2+L^2)

            A1=exp(i*2*pi/lambda*R1)
            A2=exp(i*2*pi/lambda*R2)

            I=(A1+A2).*conj(A1+A2)
            I=I/max(I)

            for k=1:length(y)
                I2(:,k)=I(k)
            end         
            imshow(I2,'Parent',app.UIAxes)
            plot(app.UIAxes2,x,I)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '杨氏双缝干涉图样')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '杨氏双缝干涉截面的光强曲线')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, 'Relative Intensity')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'interference.jpeg';
            app.mEditField_4Label = uilabel(app.UIFigure);
            app.mEditField_4Label.Text = '波长（m）';
            app.mEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = '双缝间距(m)';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField_2Label = uilabel(app.UIFigure);
            app.mEditField_2Label.Text = '观察屏离双缝间距（m）';
            app.mEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.mEditField_3Label = uilabel(app.UIFigure);
            app.mEditField_3Label.Text = '观察屏尺寸（m）';
            app.mEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '演示';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end