classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        Button_7  matlab.ui.control.Button
        Button_8  matlab.ui.control.Button
        Button_9  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            matlab.ui.control.app.Buttonapp.Button_2Pushed=newtown
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            matlab.ui.control.app.Buttonapp.Button=youngs
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            matlab.ui.control.app.Button=singlediffraction
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            matlab.ui.control.app.Button=doublediffraction
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            matlab.ui.control.app.Button=fresneldiffraction
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            matlab.ui.control.app.Button=whitelight_youngs
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            matlab.ui.control.app.Button=whitelight_single_diffraction
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            matlab.ui.control.app.Button=fft_2D
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            matlab.ui.control.app.Button=circular_fft
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '开始界面';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '杨氏双缝干涉';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '牛顿环等厚干涉';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '单缝衍射';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '双缝衍射';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '直边菲涅尔衍射';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '白光杨氏双缝干涉';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '白光单缝衍射';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '方形孔径傅里叶变换';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '圆形孔径傅里叶变换';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end