classdef newtown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RLabel       matlab.ui.control.Label
        RSlider      matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        Image        matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        R=10 %圆环半径
        lambda=0.55e-6%输入光的波长
        x,y,X,Y
        r
        I
        Ir
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x=linspace(-0.01,0.01,1001)
            app.y=app.x
            [app.X,app.Y]=meshgrid(app.x)
            app.r=sqrt(app.X.^2+app.Y.^2)
            app.I=cos(pi*(app.r.^2/app.R+app.lambda/2)/app.lambda).^2
            NCLevels=225
            colormap(app.UIAxes,gray(NCLevels))
            app.Ir=app.I*NCLevels
            image(app.UIAxes,app.x,app.y,app.Ir)
           
            plot(app.UIAxes2,app.r(501,:),app.I(501,:),'b')
          

        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            app.R = event.Value
            app.I=cos(pi*(app.r.^2/app.R+app.lambda/2)/app.lambda).^2
            NCLevels=225
            colormap(app.UIAxes,gray(NCLevels))
            app.Ir=app.I*NCLevels
            image(app.UIAxes,app.x,app.y,app.Ir)
           
            plot(app.UIAxes2,app.r(501,:),app.I(501,:),'b')
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.lambda = event.Value*1e-9
            app.I=cos(pi*(app.r.^2/app.R+app.lambda/2)/app.lambda).^2
            NCLevels=225
            colormap(app.UIAxes,gray(NCLevels))
            app.Ir=app.I*NCLevels
            image(app.UIAxes,app.x,app.y,app.Ir)
           
             plot(app.UIAxes2,app.r(501,:),app.I(501,:),'b')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = '待测透镜半径R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [10 20];
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.Value = 10;
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '检测光波长λ';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [400 760];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 550;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '牛顿环（m）')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '干涉光强分布图')
            xlabel(app.UIAxes2, 'r/m')
            ylabel(app.UIAxes2, '相对光强')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'newtown.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtown

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end