classdef newtowncircle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        Image            matlab.ui.control.Image
        Button           matlab.ui.control.Button
        mEditFieldLabel  matlab.ui.control.Label
        mEditField       matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            R=app.EditField.Value
            Lambda=app.mEditField.Value
            x=linspace(-0.01,0.01,1001)
            y=x
            [X,Y]=meshgrid(x)
            r=sqrt(X.^2+Y.^2)
            I=cos(pi*(r.^2/R+Lambda/2)/Lambda).^2
            NCLevels=225
            colormap(app.UIAxes,gray(NCLevels))
            Ir=I*NCLevels
            image(app.UIAxes,x,y,Ir)
            plot(app.UIAxes2,r(501,:),I(501,:),'b')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '牛顿环（m）')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '干涉光强分布图')
            xlabel(app.UIAxes2, 'R/m(环半径)')
            ylabel(app.UIAxes2, '相对光强')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'newtown.jpg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '演示';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = '检测光波长（m）';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '待测透镜半径';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtowncircle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end