classdef singlediffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        UIAxes          matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        nmSliderLabel   matlab.ui.control.Label
        nmSlider        matlab.ui.control.Slider
        dmmSliderLabel  matlab.ui.control.Label
        dmmSlider       matlab.ui.control.Slider
        LmSliderLabel   matlab.ui.control.Label
        LmSlider        matlab.ui.control.Slider
    end

    %%单缝衍射
    properties (Access = public)
        Property % Description
        lambda=632.8e-9%相干光波长初始值
        d=0.05e-3%狭缝宽度
        L=1%观察屏离双缝距离       
        H=0.1%观察屏尺寸，单位m
        ScreenX=1048%观察屏横向像素
        ScreenY=350%观察屏纵向像素
        x,theta,I,II
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x=linspace(-app.H/2,app.H/2,1048)
            app.theta=(app.x/app.L)
            app.I=(sin(app.d*pi*sin(app.theta)/app.lambda)./(app.d*pi*sin(app.theta)/app.lambda)).^2
            app.II=repmat(app.I,[app.ScreenY 1])     
            imshow(nthroot(app.II,5),'Parent',app.UIAxes)%增强对比度
            colormap(app.UIAxes,'gray')
            plot(app.UIAxes2,app.x,app.I)
        end

        % Value changing function: nmSlider
        function nmSliderValueChanging(app, event)
            app.lambda = event.Value * 1e-9;
            app.I=(sin(app.d*pi*sin(app.theta)/app.lambda)./(app.d*pi*sin(app.theta)/app.lambda)).^2
            app.II=repmat(app.I,[app.ScreenY 1])
            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            colormap(app.UIAxes,'gray')
            plot(app.UIAxes2,app.x,app.I)
        end

        % Value changing function: dmmSlider
        function dmmSliderValueChanging(app, event)
            app.d = event.Value*1e-3;
            app.I=(sin(app.d*pi*sin(app.theta)/app.lambda)./(app.d*pi*sin(app.theta)/app.lambda)).^2
            app.II=repmat(app.I,[app.ScreenY 1])
            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            colormap(app.UIAxes,'gray')
            plot(app.UIAxes2,app.x,app.I)
        end

        % Value changing function: LmSlider
        function LmSliderValueChanging(app, event)
            app.L = event.Value;
            app.theta=(app.x/app.L);
            app.I=(sin(app.d*pi*sin(app.theta)/app.lambda)./(app.d*pi*sin(app.theta)/app.lambda)).^2
            app.II=repmat(app.I,[app.ScreenY 1])
            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            colormap(app.UIAxes,'gray')
            plot(app.UIAxes2,app.x,app.I)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'single.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '衍射图样')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            app.nmSliderLabel = uilabel(app.UIFigure);
            app.nmSliderLabel.Text = '光波长λ/nm';
            app.nmSlider = uislider(app.UIFigure);
            app.nmSlider.Limits = [400 760];
            app.nmSlider.ValueChangingFcn = createCallbackFcn(app, @nmSliderValueChanging, true);
            app.nmSlider.Value = 632.8;
            app.dmmSliderLabel = uilabel(app.UIFigure);
            app.dmmSliderLabel.Text = '狭缝宽度d/mm';
            app.dmmSlider = uislider(app.UIFigure);
            app.dmmSlider.Limits = [0.01 5];
            app.dmmSlider.ValueChangingFcn = createCallbackFcn(app, @dmmSliderValueChanging, true);
            app.dmmSlider.Value = 0.05;
            app.LmSliderLabel = uilabel(app.UIFigure);
            app.LmSliderLabel.Text = '屏幕间距L/m';
            app.LmSlider = uislider(app.UIFigure);
            app.LmSlider.Limits = [0.1 5];
            app.LmSlider.ValueChangingFcn = createCallbackFcn(app, @LmSliderValueChanging, true);
            app.LmSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = singlediffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end