classdef whitelight_single_diffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        dmSliderLabel  matlab.ui.control.Label
        dmSlider       matlab.ui.control.Slider
        LmLabel        matlab.ui.control.Label
        LmSlider       matlab.ui.control.Slider
        HmSliderLabel  matlab.ui.control.Label
        HmSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        Property % Description
        d=0.05*1e-3%狭缝宽度
        L=1%观察屏离缝距离
        H=0.1%观察屏尺寸
        ScreenX=1048%观察屏横向像素
        ScreenY=350%观察屏纵向像素
        lambda=[400:700]%白光波长范围
        x
        theta
        Irgb
        Iw
        I,II
        Igray
        sRGB
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sRGB=spectrumRGB(app.lambda)%获取波长对应数据RGB
            app.lambda=app.lambda*1e-9%波长转换为单位m
            
            app.x=linspace(-app.H/2,app.H/2,1048)
            app.theta=atan(app.x/app.L)
            
            app.Irgb=zeros(1,app.ScreenX,3)%仿真光屏矩阵
            app.Iw=zeros(1,app.ScreenX,3)%值矩阵RGB
            for k=1:length(app.lambda)
                app.I=(sin(app.d*pi*sin(app.theta)./app.lambda(k))./(app.d*pi*sin(app.theta)/app.lambda(k))).^2
                app.Iw(1,:,1)=app.I*app.sRGB(1,k,1)%把红基色代码计入
                app.Iw(1,:,2)=app.I*app.sRGB(1,k,2)%把绿基色代码计入
                app.Iw(1,:,3)=app.I*app.sRGB(1,k,3)%把蓝基色代码计入
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
            end
            app.II=repmat(app.Irgb,[app.ScreenY 1 1])/max(max(max(app.Irgb)))
            imshow(app.II,'Parent',app.UIAxes)
            
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end

        % Value changed function: dmSlider
        function dmSliderValueChanged(app, event)
            app.d = app.dmSlider.Value*1e-3;
            for k=1:length(app.lambda)
                app.I=(sin(app.d*pi*sin(app.theta)./app.lambda(k))./(app.d*pi*sin(app.theta)/app.lambda(k))).^2
                app.Iw(1,:,1)=app.I*app.sRGB(1,k,1)
                app.Iw(1,:,2)=app.I*app.sRGB(1,k,2)
                app.Iw(1,:,3)=app.I*app.sRGB(1,k,3)
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
            end
            app.II=repmat(app.Irgb,[app.ScreenY 1 1])/max(max(max(app.Irgb)))
            imshow(app.II,'Parent',app.UIAxes)
            
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end

        % Value changed function: LmSlider
        function LmSliderValueChanged(app, event)
            app.L = app.LmSlider.Value;
            app.theta=atan(app.x/app.L)
            
            app.Irgb=zeros(1,app.ScreenX,3)
            app.Iw=zeros(1,app.ScreenX,3)
            for k=1:length(app.lambda)
                app.I=(sin(app.d*pi*sin(app.theta)./app.lambda(k))./(app.d*pi*sin(app.theta)/app.lambda(k))).^2
                app.Iw(1,:,1)=app.I*app.sRGB(1,k,1)
                app.Iw(1,:,2)=app.I*app.sRGB(1,k,2)
                app.Iw(1,:,3)=app.I*app.sRGB(1,k,3)
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
            end
            app.II=repmat(app.Irgb,[app.ScreenY 1 1])/max(max(max(app.Irgb)))
            imshow(app.II,'Parent',app.UIAxes)
            
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end

        % Value changed function: HmSlider
        function HmSliderValueChanged(app, event)
            app.H = app.HmSlider.Value;
            app.x=linspace(-app.H/2,app.H/2,1048)
            app.theta=atan(app.x/app.L)
            
            app.Irgb=zeros(1,app.ScreenX,3)
            app.Iw=zeros(1,app.ScreenX,3)
            for k=1:length(app.lambda)
                app.I=(sin(app.d*pi*sin(app.theta)./app.lambda(k))./(app.d*pi*sin(app.theta)/app.lambda(k))).^2
                app.Iw(1,:,1)=app.I*app.sRGB(1,k,1)
                app.Iw(1,:,2)=app.I*app.sRGB(1,k,2)
                app.Iw(1,:,3)=app.I*app.sRGB(1,k,3)
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
            end
            app.II=repmat(app.Irgb,[app.ScreenY 1 1])/max(max(max(app.Irgb)))
            imshow(app.II,'Parent',app.UIAxes)
            
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            app.dmSliderLabel = uilabel(app.UIFigure);
            app.dmSliderLabel.Text = '狭缝宽度d/m';
            app.dmSlider = uislider(app.UIFigure);
            app.dmSlider.Limits = [0.01 0.5];
            app.dmSlider.ValueChangedFcn = createCallbackFcn(app, @dmSliderValueChanged, true);
            app.dmSlider.Value = 0.05;
            app.LmLabel = uilabel(app.UIFigure);
            app.LmLabel.Text = '观察屏离缝距离L/m';
            app.LmSlider = uislider(app.UIFigure);
            app.LmSlider.Limits = [0.01 3];
            app.LmSlider.ValueChangedFcn = createCallbackFcn(app, @LmSliderValueChanged, true);
            app.LmSlider.Value = 1;
            app.HmSliderLabel = uilabel(app.UIFigure);
            app.HmSliderLabel.Text = '观察屏尺寸H/m';
            app.HmSlider = uislider(app.UIFigure);
            app.HmSlider.Limits = [0.01 0.5];
            app.HmSlider.ValueChangedFcn = createCallbackFcn(app, @HmSliderValueChanged, true);
            app.HmSlider.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whitelight_single_diffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end