classdef whitelight_youngs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        dmSliderLabel  matlab.ui.control.Label
        dmSlider       matlab.ui.control.Slider
        LmSliderLabel  matlab.ui.control.Label
        LmSlider       matlab.ui.control.Slider
        HmSliderLabel  matlab.ui.control.Label
        HmSlider       matlab.ui.control.Slider
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        d=0.08*1e-3%双缝间距，单位m
        L=1%观察屏距双缝距离，单位m
        H=0.1%观察屏尺寸，单位m
        ScreenX=1048%观察屏横向像素
        ScreenY=350%观察屏纵向像素
        lambda=400:700%波长范围
        sRGB
        x01,x02,x,R1,R2,A1,A2,
        Irgb,Iw,II,I,Igray
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sRGB=spectrumRGB(app.lambda)
            app.lambda=app.lambda*1e-9
            
            app.x01=app.d/2
            app.x02=-app.d/2
            app.x=linspace(-app.H/2,app.H/2,app.ScreenX)
            app.R1=sqrt((app.x-app.x01).^2+app.L^2)
            app.R2=sqrt((app.x-app.x02).^2+app.L^2)
            
            app.Irgb=zeros(app.ScreenY,app.ScreenX,3)%仿真光屏矩阵
            app.Iw=zeros(app.ScreenY,app.ScreenX,3)%值矩阵RGB
            for k=1:length(app.lambda)
                app.A1=exp(i*2*pi/app.lambda(k)*app.R1)
                app.A2=exp(i*2*pi/app.lambda(k)*app.R2)
                app.I=(app.A1+app.A2).*conj(app.A1+app.A2)
                app.Iw(:,:,1)=repmat(app.I*app.sRGB(1,k,1),[app.ScreenY 1 1])%把红基色代码计入
                app.Iw(:,:,2)=repmat(app.I*app.sRGB(1,k,2),[app.ScreenY 1 1])%把绿基色代码计入
                app.Iw(:,:,3)=repmat(app.I*app.sRGB(1,k,3),[app.ScreenY 1 1])%把蓝基色代码计入
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
                
            end
            app.II=app.Irgb/max(max(max(app.Irgb)))
            imshow(app.II,'parent',app.UIAxes)
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end

        % Value changed function: dmSlider
        function dmSliderValueChanged(app, event)
            app.d = app.dmSlider.Value*1e-3;
            app.x01=app.d/2
            app.x02=-app.d/2
            app.x=linspace(-app.H/2,app.H/2,app.ScreenX)
            app.R1=sqrt((app.x-app.x01).^2+app.L^2)
            app.R2=sqrt((app.x-app.x02).^2+app.L^2)
            
            app.Irgb=zeros(app.ScreenY,app.ScreenX,3)
            app.Iw=zeros(app.ScreenY,app.ScreenX,3)
            for k=1:length(app.lambda)
                app.A1=exp(i*2*pi/app.lambda(k)*app.R1)
                app.A2=exp(i*2*pi/app.lambda(k)*app.R2)
                app.I=(app.A1+app.A2).*conj(app.A1+app.A2)
                app.Iw(:,:,1)=repmat(app.I*app.sRGB(1,k,1),[app.ScreenY 1 1])%把红基色代码计入
                app.Iw(:,:,2)=repmat(app.I*app.sRGB(1,k,2),[app.ScreenY 1 1])%把绿基色代码计入
                app.Iw(:,:,3)=repmat(app.I*app.sRGB(1,k,3),[app.ScreenY 1 1])%把蓝基色代码计入
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
                
            end
            app.II=app.Irgb/max(max(max(app.Irgb)))
            imshow(app.II,'parent',app.UIAxes)
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end

        % Value changed function: LmSlider
        function LmSliderValueChanged(app, event)
            app.L = app.LmSlider.Value;
            app.R1=sqrt((app.x-app.x01).^2+app.L^2)
            app.R2=sqrt((app.x-app.x02).^2+app.L^2)
            
            app.Irgb=zeros(app.ScreenY,app.ScreenX,3)%仿真光屏矩阵
            app.Iw=zeros(app.ScreenY,app.ScreenX,3)%值矩阵RGB
            for k=1:length(app.lambda)
                app.A1=exp(i*2*pi/app.lambda(k)*app.R1)
                app.A2=exp(i*2*pi/app.lambda(k)*app.R2)
                app.I=(app.A1+app.A2).*conj(app.A1+app.A2)
                app.Iw(:,:,1)=repmat(app.I*app.sRGB(1,k,1),[app.ScreenY 1 1])%把红基色代码计入
                app.Iw(:,:,2)=repmat(app.I*app.sRGB(1,k,2),[app.ScreenY 1 1])%把绿基色代码计入
                app.Iw(:,:,3)=repmat(app.I*app.sRGB(1,k,3),[app.ScreenY 1 1])%把蓝基色代码计入
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
                
            end
            app.II=app.Irgb/max(max(max(app.Irgb)))
            imshow(app.II,'parent',app.UIAxes)
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end

        % Value changed function: HmSlider
        function HmSliderValueChanged(app, event)
            app.H = app.HmSlider.Value;
            app.x=linspace(-app.H/2,app.H/2,app.ScreenX)
            app.R1=sqrt((app.x-app.x01).^2+app.L^2)
            app.R2=sqrt((app.x-app.x02).^2+app.L^2)
            
            app.Irgb=zeros(app.ScreenY,app.ScreenX,3)%仿真光屏矩阵
            app.Iw=zeros(app.ScreenY,app.ScreenX,3)%值矩阵RGB
            for k=1:length(app.lambda)
                app.A1=exp(i*2*pi/app.lambda(k)*app.R1)
                app.A2=exp(i*2*pi/app.lambda(k)*app.R2)
                app.I=(app.A1+app.A2).*conj(app.A1+app.A2)
                app.Iw(:,:,1)=repmat(app.I*app.sRGB(1,k,1),[app.ScreenY 1 1])%把红基色代码计入
                app.Iw(:,:,2)=repmat(app.I*app.sRGB(1,k,2),[app.ScreenY 1 1])%把绿基色代码计入
                app.Iw(:,:,3)=repmat(app.I*app.sRGB(1,k,3),[app.ScreenY 1 1])%把蓝基色代码计入
                app.Irgb=app.Irgb+app.Iw
                app.Iw=[]
                
            end
            app.II=app.Irgb/max(max(max(app.Irgb)))
            imshow(app.II,'parent',app.UIAxes)
            
            app.Igray=rgb2gray(app.II)
            app.Igray=app.Igray/max(max(app.Igray))
            plot(app.UIAxes2,app.x,app.Igray(1,:))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.dmSliderLabel = uilabel(app.UIFigure);
            app.dmSliderLabel.Text = '双缝间距d/m';
            app.dmSlider = uislider(app.UIFigure);
            app.dmSlider.Limits = [0.01 0.3];
            app.dmSlider.ValueChangedFcn = createCallbackFcn(app, @dmSliderValueChanged, true);
            app.dmSlider.Value = 0.08;
            app.LmSliderLabel = uilabel(app.UIFigure);
            app.LmSliderLabel.Text = '观察屏距双缝距离L/m';
            app.LmSlider = uislider(app.UIFigure);
            app.LmSlider.Limits = [0.01 3];
            app.LmSlider.ValueChangedFcn = createCallbackFcn(app, @LmSliderValueChanged, true);
            app.LmSlider.Value = 0.08;
            app.HmSliderLabel = uilabel(app.UIFigure);
            app.HmSliderLabel.Text = '观察屏尺寸H/m';
            app.HmSlider = uislider(app.UIFigure);
            app.HmSlider.Limits = [0.01 0.3];
            app.HmSlider.ValueChangedFcn = createCallbackFcn(app, @HmSliderValueChanged, true);
            app.HmSlider.Value = 0.1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '干涉图样')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whitelight_youngs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end