classdef youngs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        nmSliderLabel   matlab.ui.control.Label
        nmSlider        matlab.ui.control.Slider
        dmmSliderLabel  matlab.ui.control.Label
        dmmSlider       matlab.ui.control.Slider
        DmSliderLabel   matlab.ui.control.Label
        DmSlider        matlab.ui.control.Slider
        UIAxes          matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        Image           matlab.ui.control.Image
    end

    
    properties (Access = public)

        Property % Description
        Lambda = 600 * 1e-9 % 输入光的波长（单位为nm）
        d = 1 * 1e-3% 输入两个缝的间距（单位为mm）
        D = 1 % 输入缝到屏的距离（单位为m）
        Xmax,Ymax,Tmax % 空间坐标边界
        x,y,t % 绘图变量
        r1,r2 % 光源距离
        phi % 相位差
        E1,E2,E % 合成波
        I,I0 % 光强
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Xmax = 3 * 600*1e-9 * app.D/app.d;
            app.Ymax = app.Xmax;
            app.Tmax = app.Xmax;
            %坐标取样1001份
            app.x = linspace(-app.Xmax, app.Xmax, 1001);
            app.y = linspace(-app.Ymax, app.Ymax, 1001);
            app.t = linspace(-app.Tmax, app.Tmax, 1001);
            %生成二维网格
            [app.x, app.y] = meshgrid(app.x, app.y);
            %计算复振幅
            app.r1 = sqrt((app.x+app.d/2).^2+app.D^2);
            app.r2 = sqrt((app.x-app.d/2).^2+app.D^2);
            app.phi = 2*pi*(app.r2-app.r1)/app.Lambda;
            app.E1 = 1;
            app.E2 = 1 * exp(-1i * app.phi);
            app.E = app.E1 + app.E2;
            %合成波光强
            app.I = abs(app.E) .^ 2;
            Imax1 = max(max(app.I));app.I = app.I ./ Imax1;
            
            imshow(app.I,'Parent',app.UIAxes);
            app.I0 = app.I(501, :);
            axis(app.UIAxes2,[-0.0018,0.0018,0,1]);
            plot(app.UIAxes2,app.t, app.I0);
        end

        % Value changing function: nmSlider
        function nmSliderValueChanging(app, event)
           %% T1 波长（典型值：600）
           app.Lambda = event.Value * 1e-9; % 获取改变的波长值
           app.phi = 2*pi*(app.r2-app.r1)/app.Lambda;
           app.E2 = 1 * exp(-1i * app.phi);
           app.E = app.E1 + app.E2;
           %合成波光强
           app.I = abs(app.E) .^ 2;
           Imax1 = max(max(app.I));app.I = app.I ./ Imax1;
           imshow(app.I,'Parent',app.UIAxes);
           app.I0 = app.I(501, :);
           plot(app.UIAxes2,app.t, app.I0);
        end

        % Value changing function: dmmSlider
        function dmmSliderValueChanging(app, event)
            app.d = event.Value*1e-3;%获取双缝间距的改变值
            app.r1 = sqrt((app.x+app.d/2).^2+app.D^2);
            app.r2 = sqrt((app.x-app.d/2).^2+app.D^2);
            app.phi = 2*pi*(app.r2-app.r1)/app.Lambda;
            app.E2 = 1 * exp(-1i * app.phi);
            app.E = app.E1 + app.E2;
            %合成波光强
            app.I = abs(app.E) .^ 2;
            Imax1 = max(max(app.I));app.I = app.I ./ Imax1;
            imshow(app.I,'Parent',app.UIAxes);
            app.I0 = app.I(501, :);
            plot(app.UIAxes2,app.t, app.I0);       
        end

        % Value changing function: DmSlider
        function DmSliderValueChanging(app, event)
            app.D = event.Value;
            app.r1 = sqrt((app.x+app.d/2).^2+app.D^2);
            app.r2 = sqrt((app.x-app.d/2).^2+app.D^2);
            app.phi = 2*pi*(app.r2-app.r1)/app.Lambda;
            app.E2 = 1 * exp(-1i * app.phi);
            app.E = app.E1 + app.E2;
            %合成波光强
            app.I = abs(app.E) .^ 2;
            Imax1 = max(max(app.I));app.I = app.I ./ Imax1;
            imshow(app.I,'Parent',app.UIAxes);
            app.I0 = app.I(501, :);
            plot(app.UIAxes2,app.t, app.I0);      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.nmSliderLabel = uilabel(app.UIFigure);
            app.nmSliderLabel.Text = '光波长λ/nm';
            app.nmSlider = uislider(app.UIFigure);
            app.nmSlider.Limits = [400 760];
            app.nmSlider.ValueChangingFcn = createCallbackFcn(app, @nmSliderValueChanging, true);
            app.nmSlider.Value = 600;
            app.dmmSliderLabel = uilabel(app.UIFigure);
            app.dmmSliderLabel.Text = '双缝间距d/mm';
            app.dmmSlider = uislider(app.UIFigure);
            app.dmmSlider.Limits = [0.1 5];
            app.dmmSlider.ValueChangingFcn = createCallbackFcn(app, @dmmSliderValueChanging, true);
            app.dmmSlider.Value = 1;
            app.DmSliderLabel = uilabel(app.UIFigure);
            app.DmSliderLabel.Text = '屏幕间距D/m';
            app.DmSlider = uislider(app.UIFigure);
            app.DmSlider.Limits = [0.1 5];
            app.DmSlider.ValueChangingFcn = createCallbackFcn(app, @DmSliderValueChanging, true);
            app.DmSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '干涉图样')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相干光强')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'interference.jpeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = youngs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end